classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        CoordXSpinnerLabel      matlab.ui.control.Label
        CoordXSpinner           matlab.ui.control.Spinner
        CoordYSpinnerLabel      matlab.ui.control.Label
        CoordYSpinner           matlab.ui.control.Spinner
        CoordZSpinnerLabel      matlab.ui.control.Label
        CoordZSpinner           matlab.ui.control.Spinner
        PosiodoPontoLabel       matlab.ui.control.Label
        GeometriaDropDownLabel  matlab.ui.control.Label
        GeometriaDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)
        p; % Description
        V;
        F;
        V_Corners;
        F_Corners;
        Corners;
        
        plot_p;
        plot_faces_vis;
        plot_horizonte;
        plot_objeto;
    end

    methods (Access = private)
    
        function results = replota_tudo(app)
            app.plot_p = scatter3(app.UIAxes, app.p(1), app.p(2), app.p(3), 50, [1.0, 0.0, 0.0], 'filled');
            
            [faces_visiveis, horizonte] = calcula_faces_visiveis(app.V, app.F, app.V_Corners, app.F_Corners, app.Corners, app.p);
            
            app.V(end+1, :) = app.p;
          
      
            app.plot_faces_vis = trisurf(app.F(faces_visiveis, :), app.V(:, 1), app.V(:, 2), app.V(:, 3),'Facecolor','red','FaceAlpha',0.99,'EdgeColor','none', 'parent', app.UIAxes);
            
            horizonte = horizonte(:);
            qtd = length(horizonte);
            x = [app.V(horizonte(:), 1), repmat(app.p(1), qtd, 1)]';
            y = [app.V(horizonte(:), 2), repmat(app.p(2), qtd, 1)]';
            z = [app.V(horizonte(:), 3), repmat(app.p(3), qtd, 1)]';
            app.plot_horizonte = plot3(x, y, z, '-b', 'parent', app.UIAxes);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('modelos/esfera.mat');
            app.V = V;
            hold(app.UIAxes, 'on');
            rotate3d(app.UIAxes, 'on');
            
            app.F = convhulln(app.V);
            app.plot_objeto = trimesh(app.F, app.V(:, 1), app.V(:, 2), app.V(:, 3), 'EdgeColor','k', 'parent', app.UIAxes);
            
            
            [app.V_Corners, app.F_Corners, app.Corners] = constroi_cornertable(app.V, app.F);
            
            % Inicializando o ponto
            app.p = [1.5, 0.1, -0.5];
            app.replota_tudo();
            
            app.CoordXSpinner.Value = app.p(1);
            app.CoordYSpinner.Value = app.p(2);
            app.CoordZSpinner.Value = app.p(3);
        end

        % Callback function
        function ButtonPushed(app, event)
           delete(app.plot_p);
           delete(app.plot_horizonte);
           delete(app.plot_faces_vis);
           
           app.p = app.p + [0 0 0.05];
           app.replota_tudo();
        end

        % Value changed function: CoordXSpinner
        function CoordXSpinnerValueChanged(app, event)

        end

        % Value changed function: CoordYSpinner
        function CoordYSpinnerValueChanged(app, event)

        end

        % Value changed function: CoordZSpinner
        function CoordZSpinnerValueChanged(app, event)

        end

        % Value changing function: CoordXSpinner
        function CoordXSpinnerValueChanging(app, event)
            app.p(1) = event.Value;
            
            delete(app.plot_p);
            delete(app.plot_horizonte);
            delete(app.plot_faces_vis);
            app.replota_tudo();
        end

        % Value changing function: CoordYSpinner
        function CoordYSpinnerValueChanging(app, event)
            app.p(2) = event.Value;
            
            delete(app.plot_p);
            delete(app.plot_horizonte);
            delete(app.plot_faces_vis);
            app.replota_tudo();            
        end

        % Value changing function: CoordZSpinner
        function CoordZSpinnerValueChanging(app, event)
            app.p(3) = event.Value;
            
            delete(app.plot_p);
            delete(app.plot_horizonte);
            delete(app.plot_faces_vis);
            app.replota_tudo();
            
        end

        % Value changed function: GeometriaDropDown
        function GeometriaDropDownValueChanged(app, event)
            value = app.GeometriaDropDown.Value;
            
            if( strcmp(value, 'Esfera') )
                load('modelos/esfera.mat');
            elseif( strcmp(value, 'Paraboloide') )
                load('modelos/paraboloide.mat');
            end
            
            delete( app.plot_objeto )
            delete(app.plot_p);
            delete(app.plot_horizonte);
            delete(app.plot_faces_vis);
            
            app.V = V;
            
            app.F = convhulln(app.V);
            app.plot_objeto = trimesh(app.F, app.V(:, 1), app.V(:, 2), app.V(:, 3), 'EdgeColor','k', 'parent', app.UIAxes);
            
            
            [app.V_Corners, app.F_Corners, app.Corners] = constroi_cornertable(app.V, app.F);
            
            % Inicializando o ponto
            app.p = [1.5, 0.1, -0.5];
            app.replota_tudo();
            
            app.CoordXSpinner.Value = app.p(1);
            app.CoordYSpinner.Value = app.p(2);
            app.CoordZSpinner.Value = app.p(3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Fecho Convexo e faces visíveis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CoordXSpinnerLabel = uilabel(app.UIFigure);
            app.CoordXSpinnerLabel.Text = 'Coord. X';
            app.CoordXSpinner = uispinner(app.UIFigure);
            app.CoordXSpinner.Step = 0.05;
            app.CoordXSpinner.ValueChangingFcn = createCallbackFcn(app, @CoordXSpinnerValueChanging, true);
            app.CoordXSpinner.ValueChangedFcn = createCallbackFcn(app, @CoordXSpinnerValueChanged, true);
            app.CoordYSpinnerLabel = uilabel(app.UIFigure);
            app.CoordYSpinnerLabel.Text = 'Coord. Y';
            app.CoordYSpinner = uispinner(app.UIFigure);
            app.CoordYSpinner.Step = 0.05;
            app.CoordYSpinner.ValueChangingFcn = createCallbackFcn(app, @CoordYSpinnerValueChanging, true);
            app.CoordYSpinner.ValueChangedFcn = createCallbackFcn(app, @CoordYSpinnerValueChanged, true);
            app.CoordZSpinnerLabel = uilabel(app.UIFigure);
            app.CoordZSpinnerLabel.Text = 'Coord. Z';
            app.CoordZSpinner = uispinner(app.UIFigure);
            app.CoordZSpinner.Step = 0.05;
            app.CoordZSpinner.ValueChangingFcn = createCallbackFcn(app, @CoordZSpinnerValueChanging, true);
            app.CoordZSpinner.ValueChangedFcn = createCallbackFcn(app, @CoordZSpinnerValueChanged, true);
            app.PosiodoPontoLabel = uilabel(app.UIFigure);
            app.PosiodoPontoLabel.Text = 'Posição do Ponto';
            app.GeometriaDropDownLabel = uilabel(app.UIFigure);
            app.GeometriaDropDownLabel.Text = 'Geometria:';
            app.GeometriaDropDown = uidropdown(app.UIFigure);
            app.GeometriaDropDown.Items = {'Esfera', 'Paraboloide'};
            app.GeometriaDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometriaDropDownValueChanged, true);
            app.GeometriaDropDown.Value = 'Esfera';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end