classdef app_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReconstruodesuperfciespornuvemdepontosUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        NuvemdepontosDropDownLabel     matlab.ui.control.Label
        NuvemdepontosDropDown          matlab.ui.control.DropDown
        TipodefunoLabel                matlab.ui.control.Label
        TipoFuncaoDropDown             matlab.ui.control.DropDown
        CentrosporpartioSpinnerLabel   matlab.ui.control.Label
        CentrosporpartioSpinner        matlab.ui.control.Spinner
        GridparapartioSpinnerLabel     matlab.ui.control.Label
        GridparapartioSpinner          matlab.ui.control.Spinner
        GriddareconstruoSpinnerLabel   matlab.ui.control.Label
        GriddareconstruoSpinner        matlab.ui.control.Spinner
        GriddepontosfantasmasSpinnerLabel  matlab.ui.control.Label
        GriddepontosfantasmasSpinner   matlab.ui.control.Spinner
        ParmetrosparaareconstruoLabel  matlab.ui.control.Label
        VisualizaoLabel                matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        NuvemdepontosCheckBox          matlab.ui.control.CheckBox
        SuperfciereconstrudaCheckBox   matlab.ui.control.CheckBox
        CentrosescolhidosCheckBox      matlab.ui.control.CheckBox
        PontosfantasmasCheckBox        matlab.ui.control.CheckBox
        VetoresNormaisCheckBox         matlab.ui.control.CheckBox
    end


    properties (Access = private)
        patchSurface;
        plotCentros;
        plotNuvem;
        plotParticoes;
        plotFantasmas;
        plotNormais;
        
        
        P, N;
        P_fantasma, N_fantasma;
        P_Particoes;
        centros_parts;
        x_grid, y_grid, z_grid, F_grid;
        
        Property5 % Description
    end

    methods (Access = private)
    
        function results = calcularTudoNovamente(app)
            clear textprogressbar;
            
            app.SuperfciereconstrudaCheckBox.Value = true;
            app.CentrosescolhidosCheckBox.Value = false;
            app.NuvemdepontosCheckBox.Value = false;
            app.PontosfantasmasCheckBox.Value = false;
            app.VetoresNormaisCheckBox.Value = false;
            
            delete(app.patchSurface);
            delete(app.plotCentros);
            delete(app.plotNuvem);
            delete(app.plotParticoes);
            delete(app.plotFantasmas);
            delete(app.plotNormais);
            
            
            nome_modelo = app.NuvemdepontosDropDown.Value(1:end-4);
            
            tipo_interpolacao = app.TipoFuncaoDropDown.Value;
            
            % Quantidade de particoes de unidade em cada direcao
            Mx = app.GridparapartioSpinner.Value;
            My = Mx;
            Mz = Mx;
            
            % Quantidade de centros por particao
            N_parts = app.CentrosporpartioSpinner.Value;
            
            % Dimensao do grid pra avaliar a funcao no final e reconstruir a superficie
            dim_grid = app.GriddareconstruoSpinner.Value;
            
            % Dimensao do grid fantasma usado para adicionar pontos fantasmas em regioes muito vazias
            % Quanto maior, mais ele remove as folhas falsas no LSHRBF, mas pode dar uma piorada na superficie
            grid_fantasma = app.GriddepontosfantasmasSpinner.Value;


            load(['modelos/', nome_modelo, '.mat']);

            Valores = zeros( size(P, 1), 1 );
            
            
            % Deixando as normais unitarias
            for i=1:size(N, 1)
            	N(i, :) = N(i, :)/norm(N(i, :));
            end
            
            pmin = min(P, [], 1);
            pmax = max(P, [], 1);
            centroid = sum(P, 1);
            
            psize = pmax - pmin;
            centroid = psize / 2;
            for i=1:size(P, 1)
              P(i,1:3) = ((P(i,1:3) - pmin - centroid) / max(psize));
              P(i,1:3) = P(i,1:3) * 10;
            end
            
            min_pou = min(P, [], 1);
            max_pou = max(P, [], 1);
            pou_size = max_pou - min_pou;
            
            % Add margin
            margem_fator = 0.25;
            max_pou = max_pou + pou_size*margem_fator;
            min_pou = min_pou - pou_size*margem_fator;
            
            k1 = 100.0/norm(max_pou - min_pou);
            k2 = 0.5;
            
            
            % RBF e suas derivadas
            psi = @(x)( norm(x).^3 );
            psi_x = @(x)( 3.0*norm(x)*x(1) );
            psi_y = @(x)( 3.0*norm(x)*x(2) );
            psi_z = @(x)( 3.0*norm(x)*x(3) );
            psi_xx = @(x)( 3.0*( norm(x)^2 + x(1)^2 )/norm(x) );
            psi_yy = @(x)( 3.0*( norm(x)^2 + x(2)^2 )/norm(x) );
            psi_zz = @(x)( 3.0*( norm(x)^2 + x(3)^2 )/norm(x) );
            psi_xy = @(x)( 3.0*( x(1)*x(2) )/norm(x) );
            psi_xz = @(x)( 3.0*( x(1)*x(3) )/norm(x) );
            psi_yz = @(x)( 3.0*( x(2)*x(3) )/norm(x) );
            
            H_psi = @(x) ...
            	( [...
            		psi_xx(x), psi_xy(x), psi_xz(x);
            		psi_xy(x), psi_yy(x), psi_yz(x);
            		psi_xz(x), psi_yz(x), psi_zz(x)
            	]);
            
            grad_psi = @(x)( [ psi_x(x); psi_y(x); psi_z(x) ] );
            
            
            [S, T, P_Particoes] = cria_particoes_unidade(P, N, Valores, Mx, My, Mz, N_parts, min_pou, max_pou);
            
            centros_parts = escolhe_centros( ...
            							N_parts, tipo_interpolacao, ...	
            							P, N, Valores, P_Particoes, 1, ...
            							k1, k2, ...
            							psi, psi_x, psi_y, psi_z, ...
            							psi_xx, psi_xy, psi_xz, psi_yy, psi_yz, psi_zz ...
            						);
            
            
            % Adicionando pontos fantasmas em regioes muito vazias
            % Isso ajuda a remover folhas falsas de nivel zero
            [P_fantasma, N_fantasma, V_fantasma] = cria_pontos_fantasmas(P, N, min_pou, max_pou, grid_fantasma);
            app.P = P;
            app.P_fantasma = P_fantasma;
            app.N = N;
            app.N_fantasma = N_fantasma;
            disp(['Pontos fantasmas adicionados: ', num2str(size(P_fantasma, 1))] );
            P = [P; P_fantasma];
            N = [N; N_fantasma];
            Valores = [Valores; V_fantasma];
            [S, T, P_Particoes] = cria_particoes_unidade(P, N, Valores, Mx, My, Mz, N_parts, min_pou, max_pou);


            % Calculando a funcao interpoladora local de cada particao
            disp('Calculando a funcao interpoladora final de cada particao...')
            alfa = cell(Mx, My, Mz);
            beta = cell(Mx, My, Mz);
            lambda = cell(Mx, My, Mz);
            base_p = cell(Mx, My, Mz);
            for i_part = 1:Mx
            	for j_part = 1:My
            		for k_part = 1:Mz
            
            			% Pegando apenas os pontos desta particao
            			indices_pontos = P_Particoes{i_part, j_part, k_part};
            			P_Local = P(indices_pontos, :);
            			N_Local = N(indices_pontos, :);
            			V_Local = Valores(indices_pontos);
            			centros = centros_parts{i_part, j_part, k_part};
            
            
            			if( strcmp(tipo_interpolacao, 'HRBF') )
            				[alfa_aux, beta_aux, lambda_aux, base_p_aux] = ...
            											determina_coeficientes_HRBF( ...	
            												P_Local(centros, :), N_Local(centros, :), V_Local(centros), 1, ...
            												psi, psi_x, psi_y, psi_z, ...
            												psi_xx, psi_xy, psi_xz, psi_yy, psi_yz, psi_zz, ...
            												true ...
            											);
            			elseif( strcmp(tipo_interpolacao, 'LSHRBF') )
            				[alfa_aux, beta_aux, lambda_aux, base_p_aux] = ...
            											determina_coeficientes_LSHRBF( ...	
            												P_Local, N_Local, P_Local(centros, :), V_Local, 1, ...
            												psi, psi_x, psi_y, psi_z, ...
            												psi_xx, psi_xy, psi_xz, psi_yy, psi_yz, psi_zz, ...
            												true ...
            											);
            			end
            
            			alfa{i_part, j_part, k_part} = alfa_aux;
            			beta{i_part, j_part, k_part} = beta_aux;
            			lambda{i_part, j_part, k_part} = lambda_aux;
            			base_p{i_part, j_part, k_part} = base_p_aux;
            
            		end
            	end
            end


            intervalo_x = 0.5*[S{1, 1, 1}(1) + min(P(:, 1)), T{Mx, My, Mz}(1) + max(P(:, 1))];
            intervalo_y = 0.5*[S{1, 1, 1}(2) + min(P(:, 2)), T{Mx, My, Mz}(2) + max(P(:, 2))];
            intervalo_z = 0.5*[S{1, 1, 1}(3) + min(P(:, 3)), T{Mx, My, Mz}(3) + max(P(:, 3))];
            tx = linspace(intervalo_x(1), intervalo_x(2), dim_grid);
            ty = linspace(intervalo_y(1), intervalo_y(2), dim_grid);
            tz = linspace(intervalo_z(1), intervalo_z(2), dim_grid);
            [x_grid, y_grid, z_grid] = meshgrid(tx, ty, tz);
            F_grid = zeros(dim_grid, dim_grid, dim_grid);
            
            textprogressbar('Avaliando a funcao no grid: ');
            for( i=1:size(x_grid, 1) )
            	textprogressbar( 100*i/size(x_grid, 1) );
            	for( j=1:size(x_grid, 2) )
            		for( k=1:size(x_grid, 3) )
            			x = [x_grid(i, j, k), y_grid(i, j, k), z_grid(i, j, k)];
            
            			F_grid(i, j, k) = funcao_interp_particao( ...
            								x, P, P_Particoes, centros_parts, S, T, alfa, beta, lambda, ...
            								psi, psi_x, psi_y, psi_z, base_p);
            
            		end
            	end
            end
            textprogressbar(100);
            textprogressbar('');


            % Plotando
            %grid on;
            fv = isosurface(x_grid, y_grid, z_grid, F_grid, 0.0);
            p = patch(app.UIAxes, fv);
            
            
            isonormals(x_grid, y_grid, z_grid, F_grid, p);
            set(p,'FaceColor',[.9 .9 .9],'EdgeColor','none');
            daspect(app.UIAxes, [1 1 1]);
            %cl1 = camlight(app.UIAxes, -20,  55,'infinite'); set(cl1, 'Color', [1    0.65 0.15]);
            %cl3 = camlight(app.UIAxes,  135,-20,'infinite'); set(cl3, 'Color', [1    0.55 0.15]);
            %cl2 = camlight(app.UIAxes,  20, -55,'infinite'); set(cl2, 'Color', [0.15 0.65 1   ]);
            %cl4 = camlight(app.UIAxes, -135, 20,'infinite'); set(cl4, 'Color', [0.15 0.55 1   ]);
        
            material(app.UIAxes, 'dull');
            lighting(app.UIAxes, 'phong');
            alpha(app.UIAxes, 1.0)
            
            rotate3d(app.UIAxes, 'on')
            hold(app.UIAxes, 'on')
            
            app.patchSurface = p;
            app.plotCentros = [];
            app.plotNuvem = [];
            app.plotParticoes = [];
            app.plotFantasmas = [];
            app.plotNormais = [];
            
            app.x_grid = x_grid;
            app.y_grid = y_grid;
            app.z_grid = z_grid;
            app.F_grid = F_grid;
            
            
            app.P_Particoes = P_Particoes;
            app.centros_parts = centros_parts;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            arquivos = dir('modelos/*.*');
            
            arqs_mat = cell(0);
            for i = 1:length(arquivos)
                nome = arquivos(i).name
                if( endsWith(nome, '.mat') )
                    arqs_mat{end+1} = nome;
                end
            end
            
            app.NuvemdepontosDropDown.Items = arqs_mat;
            
            cl1 = camlight(app.UIAxes, -20,  55,'infinite'); set(cl1, 'Color', [1    0.65 0.15]);
            cl3 = camlight(app.UIAxes,  135,-20,'infinite'); set(cl3, 'Color', [1    0.55 0.15]);
            cl2 = camlight(app.UIAxes,  20, -55,'infinite'); set(cl2, 'Color', [0.15 0.65 1   ]);
            cl4 = camlight(app.UIAxes, -135, 20,'infinite'); set(cl4, 'Color', [0.15 0.55 1   ]);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            app.calcularTudoNovamente()
        end

        % Value changed function: CentrosescolhidosCheckBox
        function CentrosescolhidosCheckBoxValueChanged(app, event)
            value = app.CentrosescolhidosCheckBox.Value;
            
            if( value==false )
                delete(app.plotCentros);
                app.plotCentros = [];
                return;
            end
            
            
            Mx = size(app.centros_parts, 1);
            My = size(app.centros_parts, 2);
            Mz = size(app.centros_parts, 3);
            
            P = [app.P; app.P_fantasma];
            
            for i_part = 1:Mx
            	for j_part = 1:My
            		for k_part = 1:Mz
        			P_Local = P(app.P_Particoes{i_part, j_part, k_part}, :);
        			centros = app.centros_parts{i_part, j_part, k_part};
        			novoplot = scatter3(app.UIAxes, P_Local(centros, 1), P_Local(centros, 2), P_Local(centros, 3), 50, 'red', 'filled', 'MarkerEdgeColor', 'k');
           
           
                                app.plotCentros(end+1) = novoplot;
            		end
            	end
            end
        end

        % Value changed function: NuvemdepontosCheckBox
        function NuvemdepontosCheckBoxValueChanged(app, event)
            value = app.NuvemdepontosCheckBox.Value;
            
            if( value==false )
                delete(app.plotNuvem);
                app.plotNuvem = [];
                return;
            end
            
            if( value==true && isempty(app.P) )
                return;
            end
           
            
            app.plotNuvem = scatter3(app.UIAxes, app.P(:, 1), app.P(:, 2), app.P(:, 3), 50, 'blue', 'filled', 'MarkerEdgeColor', 'k');		
           
        end

        % Value changed function: SuperfciereconstrudaCheckBox
        function SuperfciereconstrudaCheckBoxValueChanged(app, event)
            value = app.SuperfciereconstrudaCheckBox.Value;
            
            if( value==false )
                delete(app.patchSurface)
                return;
            end
            
            fv = isosurface(app.x_grid, app.y_grid, app.z_grid, app.F_grid, 0.0);
            p = patch(app.UIAxes, fv);
            
            isonormals(app.x_grid, app.y_grid, app.z_grid, app.F_grid, p);
            set(p,'FaceColor',[.9 .9 .9],'EdgeColor','none');
            %cl1 = camlight(app.UIAxes, -20,  55,'infinite'); set(cl1, 'Color', [1    0.65 0.15]);
            %cl3 = camlight(app.UIAxes,  135,-20,'infinite'); set(cl3, 'Color', [1    0.55 0.15]);
            %cl2 = camlight(app.UIAxes,  20, -55,'infinite'); set(cl2, 'Color', [0.15 0.65 1   ]);
            %cl4 = camlight(app.UIAxes, -135, 20,'infinite'); set(cl4, 'Color', [0.15 0.55 1   ]);
        
            material(app.UIAxes, 'dull');
            lighting(app.UIAxes, 'phong');
            
            
            app.patchSurface = p;
            
            
        end

        % Value changed function: PontosfantasmasCheckBox
        function PontosfantasmasCheckBoxValueChanged(app, event)
            value = app.PontosfantasmasCheckBox.Value;
            
            if( value==false )
                delete(app.plotFantasmas);
                app.plotFantasmas = [];
                return;
            end
            
            if( value==true && isempty(app.P_fantasma) )
                return;
            end
            
            app.plotFantasmas = scatter3(app.UIAxes, app.P_fantasma(:, 1), app.P_fantasma(:, 2), app.P_fantasma(:, 3), 50, 'green', 'filled', 'MarkerEdgeColor', 'k');	
        end

        % Value changed function: VetoresNormaisCheckBox
        function VetoresNormaisCheckBoxValueChanged(app, event)
            value = app.VetoresNormaisCheckBox.Value;
            
            if( value==false )
                delete(app.plotNormais);
                app.plotNormais = [];
                return;
            end
            
            if( value==true && isempty(app.N) )
                return;
            end
            
            app.plotNormais = quiver3(app.UIAxes, app.P(:, 1), app.P(:, 2), app.P(:, 3), ...
                app.N(:, 1), app.N(:, 2), app.N(:, 3), 'yellow');	
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReconstruodesuperfciespornuvemdepontosUIFigure = uifigure;
            app.ReconstruodesuperfciespornuvemdepontosUIFigure.Name = 'Reconstrução de superfícies por nuvem de pontos';
            app.UIAxes = uiaxes(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NuvemdepontosDropDownLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.NuvemdepontosDropDownLabel.Text = 'Nuvem de pontos: ';
            app.NuvemdepontosDropDown = uidropdown(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.NuvemdepontosDropDown.Items = {};
            app.NuvemdepontosDropDown.Value = {};
            app.TipodefunoLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.TipodefunoLabel.Text = 'Tipo de função: ';
            app.TipoFuncaoDropDown = uidropdown(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.TipoFuncaoDropDown.Items = {'HRBF', 'LSHRBF'};
            app.TipoFuncaoDropDown.Value = 'HRBF';
            app.CentrosporpartioSpinnerLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.CentrosporpartioSpinnerLabel.Text = 'Centros por partição: ';
            app.CentrosporpartioSpinner = uispinner(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.CentrosporpartioSpinner.Limits = [1 Inf];
            app.CentrosporpartioSpinner.Value = 20;
            app.GridparapartioSpinnerLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.GridparapartioSpinnerLabel.Text = 'Grid para partição: ';
            app.GridparapartioSpinner = uispinner(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.GridparapartioSpinner.Limits = [1 Inf];
            app.GridparapartioSpinner.Value = 1;
            app.GriddareconstruoSpinnerLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.GriddareconstruoSpinnerLabel.Text = 'Grid da reconstrução: ';
            app.GriddareconstruoSpinner = uispinner(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.GriddareconstruoSpinner.Limits = [1 Inf];
            app.GriddareconstruoSpinner.Value = 30;
            app.GriddepontosfantasmasSpinnerLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.GriddepontosfantasmasSpinnerLabel.Text = 'Grid de pontos fantasmas: ';
            app.GriddepontosfantasmasSpinner = uispinner(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.GriddepontosfantasmasSpinner.Limits = [1 Inf];
            app.GriddepontosfantasmasSpinner.Value = 1;
            app.ParmetrosparaareconstruoLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.ParmetrosparaareconstruoLabel.Text = 'Parâmetros para a reconstrução';
            app.VisualizaoLabel = uilabel(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.VisualizaoLabel.Text = 'Visualização';
            app.CalcularButton = uibutton(app.ReconstruodesuperfciespornuvemdepontosUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.NuvemdepontosCheckBox = uicheckbox(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.NuvemdepontosCheckBox.ValueChangedFcn = createCallbackFcn(app, @NuvemdepontosCheckBoxValueChanged, true);
            app.NuvemdepontosCheckBox.Text = 'Nuvem de pontos';
            app.SuperfciereconstrudaCheckBox = uicheckbox(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.SuperfciereconstrudaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuperfciereconstrudaCheckBoxValueChanged, true);
            app.SuperfciereconstrudaCheckBox.Text = 'Superfície reconstruída';
            app.CentrosescolhidosCheckBox = uicheckbox(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.CentrosescolhidosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CentrosescolhidosCheckBoxValueChanged, true);
            app.CentrosescolhidosCheckBox.Text = 'Centros escolhidos';
            app.PontosfantasmasCheckBox = uicheckbox(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.PontosfantasmasCheckBox.ValueChangedFcn = createCallbackFcn(app, @PontosfantasmasCheckBoxValueChanged, true);
            app.PontosfantasmasCheckBox.Text = 'Pontos fantasmas';
            app.VetoresNormaisCheckBox = uicheckbox(app.ReconstruodesuperfciespornuvemdepontosUIFigure);
            app.VetoresNormaisCheckBox.ValueChangedFcn = createCallbackFcn(app, @VetoresNormaisCheckBoxValueChanged, true);
            app.VetoresNormaisCheckBox.Text = 'Vetores Normais';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReconstruodesuperfciespornuvemdepontosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReconstruodesuperfciespornuvemdepontosUIFigure)
        end
    end
end